classdef data_export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportUIFigure              matlab.ui.Figure
        TreadmillEditFieldLabel     matlab.ui.control.Label
        TreadmillEditField          matlab.ui.control.EditField
        TreadmillBrowseButton       matlab.ui.control.Button
        MetabolicsBrowseButton      matlab.ui.control.Button
        MetabolicsEditFieldLabel    matlab.ui.control.Label
        MetabolicsEditField         matlab.ui.control.EditField
        OutputBrowseButton          matlab.ui.control.Button
        OutputEditFieldLabel        matlab.ui.control.Label
        OutputEditField             matlab.ui.control.EditField
        ExportButton                matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        Condition1Tab               matlab.ui.container.Tab
        Condition1DropDownLabel     matlab.ui.control.Label
        Condition1DropDown          matlab.ui.control.DropDown
        Parameter1C1DropDownLabel   matlab.ui.control.Label
        Parameter1C1DropDown        matlab.ui.control.DropDown
        Parameter2C1DropDownLabel   matlab.ui.control.Label
        Parameter2C1DropDown        matlab.ui.control.DropDown
        Parameter3C1DropDownLabel   matlab.ui.control.Label
        Parameter3C1DropDown        matlab.ui.control.DropDown
        Parameter4C1DropDownLabel   matlab.ui.control.Label
        Parameter4C1DropDown        matlab.ui.control.DropDown
        MetabolicsC1Panel           matlab.ui.container.Panel
        MFileC1DropDownLabel        matlab.ui.control.Label
        MFileC1DropDown             matlab.ui.control.DropDown
        MParameter1C1DropDownLabel  matlab.ui.control.Label
        MParameter1C1DropDown       matlab.ui.control.DropDown
        MParameter2C1DropDownLabel  matlab.ui.control.Label
        MParameter2C1DropDown       matlab.ui.control.DropDown
        Condition2Tab               matlab.ui.container.Tab
        Condition2DropDownLabel     matlab.ui.control.Label
        Condition2DropDown          matlab.ui.control.DropDown
        Parameter1C2DropDownLabel   matlab.ui.control.Label
        Parameter1C2DropDown        matlab.ui.control.DropDown
        Parameter2C2DropDownLabel   matlab.ui.control.Label
        Parameter2C2DropDown        matlab.ui.control.DropDown
        Parameter3C2DropDownLabel   matlab.ui.control.Label
        Parameter3C2DropDown        matlab.ui.control.DropDown
        Parameter4C2DropDownLabel   matlab.ui.control.Label
        Parameter4C2DropDown        matlab.ui.control.DropDown
        MetabolicsC2Panel           matlab.ui.container.Panel
        MFileC2DropDownLabel        matlab.ui.control.Label
        MFileC2DropDown             matlab.ui.control.DropDown
        MParameter1C2DropDownLabel  matlab.ui.control.Label
        MParameter1C2DropDown       matlab.ui.control.DropDown
        MParameter2C2DropDownLabel  matlab.ui.control.Label
        MParameter2C2DropDown       matlab.ui.control.DropDown
        Condition3Tab               matlab.ui.container.Tab
        Condition3DropDownLabel     matlab.ui.control.Label
        Condition3DropDown          matlab.ui.control.DropDown
        Parameter1C3DropDownLabel   matlab.ui.control.Label
        Parameter1C3DropDown        matlab.ui.control.DropDown
        Parameter2C3DropDownLabel   matlab.ui.control.Label
        Parameter2C3DropDown        matlab.ui.control.DropDown
        Parameter3C3DropDownLabel   matlab.ui.control.Label
        Parameter3C3DropDown        matlab.ui.control.DropDown
        Parameter4C3DropDownLabel   matlab.ui.control.Label
        Parameter4C3DropDown        matlab.ui.control.DropDown
        MetabolicsC3Panel           matlab.ui.container.Panel
        MFileC3DropDownLabel        matlab.ui.control.Label
        MFileC3DropDown             matlab.ui.control.DropDown
        MParameter1C3DropDownLabel  matlab.ui.control.Label
        MParameter1C3DropDown       matlab.ui.control.DropDown
        MParameter2C3DropDownLabel  matlab.ui.control.Label
        MParameter2C3DropDown       matlab.ui.control.DropDown
        Condition4Tab               matlab.ui.container.Tab
        Condition4DropDownLabel     matlab.ui.control.Label
        Condition4DropDown          matlab.ui.control.DropDown
        Parameter1C4DropDownLabel   matlab.ui.control.Label
        Parameter1C4DropDown        matlab.ui.control.DropDown
        Parameter2C4DropDownLabel   matlab.ui.control.Label
        Parameter2C4DropDown        matlab.ui.control.DropDown
        Parameter3C4DropDownLabel   matlab.ui.control.Label
        Parameter3C4DropDown        matlab.ui.control.DropDown
        Parameter4C4DropDownLabel   matlab.ui.control.Label
        Parameter4C4DropDown        matlab.ui.control.DropDown
        MetabolicsC4Panel           matlab.ui.container.Panel
        MFileC4DropDownLabel        matlab.ui.control.Label
        MFileC4DropDown             matlab.ui.control.DropDown
        MParameter1C4DropDownLabel  matlab.ui.control.Label
        MParameter1C4DropDown       matlab.ui.control.DropDown
        MParameter2C4DropDownLabel  matlab.ui.control.Label
        MParameter2C4DropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: TreadmillBrowseButton
        function TreadmillBrowseButtonPushed(app, event)
            [filename, pathname] = uigetfile({ ...
                    '*.mat', 'MAT-files (*.mat)'; ...
                    '*.*', 'All Files (*.*)' ...
                }, ...
                'Select a MATLAB data file');
            
            if ~isequal(filename, 0)
                app.TreadmillEditField.Value = fullfile(pathname, filename);
                if ~isempty(app.MetabolicsEditField.Value)
                    app.OutputEditField.Enable = 'on';
                    app.OutputEditFieldLabel.Enable = 'on';
                    app.OutputBrowseButton.Enable = 'on';
                else
                    app.OutputEditField.Enable = 'off';
                    app.OutputEditFieldLabel.Enable = 'off';
                    app.OutputBrowseButton.Enable = 'off';
                end
                
                step_filename = app.TreadmillEditField.Value;
                load(step_filename, 'adaptData');
                
                app.Condition1DropDown.Items = [{'None'}, adaptData.metaData.conditionName];
                app.Condition2DropDown.Items = [{'None'}, adaptData.metaData.conditionName];
                app.Condition3DropDown.Items = [{'None'}, adaptData.metaData.conditionName];
                app.Condition4DropDown.Items = [{'None'}, adaptData.metaData.conditionName];
                
                parameters = adaptData.getParameterList();
                app.Parameter1C1DropDown.Items = [{'None'}; parameters];
                app.Parameter2C1DropDown.Items = [{'None'}; parameters];
                app.Parameter3C1DropDown.Items = [{'None'}; parameters];
                app.Parameter4C1DropDown.Items = [{'None'}; parameters];
                app.Parameter1C2DropDown.Items = [{'None'}; parameters];
                app.Parameter2C2DropDown.Items = [{'None'}; parameters];
                app.Parameter3C2DropDown.Items = [{'None'}; parameters];
                app.Parameter4C2DropDown.Items = [{'None'}; parameters];
                app.Parameter1C3DropDown.Items = [{'None'}; parameters];
                app.Parameter2C3DropDown.Items = [{'None'}; parameters];
                app.Parameter3C3DropDown.Items = [{'None'}; parameters];
                app.Parameter4C3DropDown.Items = [{'None'}; parameters];
                app.Parameter1C4DropDown.Items = [{'None'}; parameters];
                app.Parameter2C4DropDown.Items = [{'None'}; parameters];
                app.Parameter3C4DropDown.Items = [{'None'}; parameters];
                app.Parameter4C4DropDown.Items = [{'None'}; parameters];
                
                app.Condition1DropDown.Enable = 'on';
                app.Condition1DropDownLabel.Enable = 'on';
            end
        end

        % Button pushed function: MetabolicsBrowseButton
        function MetabolicsBrowseButtonPushed(app, event)
            multiselect = 'on';
            
            [filename, pathname] = uigetfile({ ...
                    '*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'; ...
                    '*.*', 'All Files (*.*)' ...
                }, ...
                'Select a EXCEL spreadsheet file', ...
                'MultiSelect', multiselect);
            
            if ~isequal(filename, 0)
                filename = cellstr(filename);
                filepaths = '';
                for k = 1:length(filename)
                    filepaths = [fullfile(pathname, filename{k}) ';' filepaths];
                end
                
                app.MetabolicsEditField.Value = filepaths(1:end-1);
                
                app.MFileC1DropDown.Items = filename;
                app.MFileC2DropDown.Items = filename;
                app.MFileC3DropDown.Items = filename;
                app.MFileC4DropDown.Items = filename;
                
                files = strsplit(filepaths(1:end-1), ';');
                [~, ~, raw] = xlsread(files{1});
                col = find(strcmp(raw(1, :), 't')) + 1;
                app.MParameter1C1DropDown.Items = raw(1, col:end);
                app.MParameter2C1DropDown.Items = [{'None'}, raw(1, col:end)];
                app.MParameter1C2DropDown.Items = raw(1, col:end);
                app.MParameter2C2DropDown.Items = [{'None'}, raw(1, col:end)];
                app.MParameter1C3DropDown.Items = raw(1, col:end);
                app.MParameter2C3DropDown.Items = [{'None'}, raw(1, col:end)];
                app.MParameter1C4DropDown.Items = raw(1, col:end);
                app.MParameter2C4DropDown.Items = [{'None'}, raw(1, col:end)];
                
                app.MParameter1C1DropDown.Value = 'VO2/Kg';
                app.MParameter1C2DropDown.Value = 'VO2/Kg';
                app.MParameter1C3DropDown.Value = 'VO2/Kg';
                app.MParameter1C4DropDown.Value = 'VO2/Kg';
                
                if length(filename) > 1
                    app.MFileC1DropDown.Enable = 'on';
                    app.MFileC1DropDownLabel.Enable = 'on';
                    app.MFileC2DropDown.Enable = 'on';
                    app.MFileC2DropDownLabel.Enable = 'on';
                    app.MFileC3DropDown.Enable = 'on';
                    app.MFileC3DropDownLabel.Enable = 'on';
                    app.MFileC4DropDown.Enable = 'on';
                    app.MFileC4DropDownLabel.Enable = 'on';
                else
                    app.MFileC1DropDown.Enable = 'off';
                    app.MFileC1DropDownLabel.Enable = 'off';
                    app.MFileC2DropDown.Enable = 'off';
                    app.MFileC2DropDownLabel.Enable = 'off';
                    app.MFileC3DropDown.Enable = 'off';
                    app.MFileC3DropDownLabel.Enable = 'off';
                    app.MFileC4DropDown.Enable = 'off';
                    app.MFileC4DropDownLabel.Enable = 'off';
                end
                
                app.MParameter1C1DropDown.Enable = 'on';
                app.MParameter1C1DropDownLabel.Enable = 'on';
                app.MParameter2C1DropDown.Enable = 'on';
                app.MParameter2C1DropDownLabel.Enable = 'on';
                app.MParameter1C2DropDown.Enable = 'on';
                app.MParameter1C2DropDownLabel.Enable = 'on';
                app.MParameter2C2DropDown.Enable = 'on';
                app.MParameter2C2DropDownLabel.Enable = 'on';
                app.MParameter1C3DropDown.Enable = 'on';
                app.MParameter1C3DropDownLabel.Enable = 'on';
                app.MParameter2C3DropDown.Enable = 'on';
                app.MParameter2C3DropDownLabel.Enable = 'on';
                app.MParameter1C4DropDown.Enable = 'on';
                app.MParameter1C4DropDownLabel.Enable = 'on';
                app.MParameter2C4DropDown.Enable = 'on';
                app.MParameter2C4DropDownLabel.Enable = 'on';
                
                if ~isempty(app.TreadmillEditField.Value)
                    app.OutputEditField.Enable = 'on';
                    app.OutputEditFieldLabel.Enable = 'on';
                    app.OutputBrowseButton.Enable = 'on';
                else
                    app.OutputEditField.Enable = 'off';
                    app.OutputEditFieldLabel.Enable = 'off';
                    app.OutputBrowseButton.Enable = 'off';
                end
            else
                app.OutputEditField.Enable = 'off';
                app.OutputEditFieldLabel.Enable = 'off';
                app.OutputBrowseButton.Enable = 'off';
                
                app.MFileC1DropDown.Enable = 'off';
                app.MFileC1DropDownLabel.Enable = 'off';
                app.MFileC2DropDown.Enable = 'off';
                app.MFileC2DropDownLabel.Enable = 'off';
                app.MFileC3DropDown.Enable = 'off';
                app.MFileC3DropDownLabel.Enable = 'off';
                app.MFileC4DropDown.Enable = 'off';
                app.MFileC4DropDownLabel.Enable = 'off';
                
                app.MParameter1C1DropDown.Enable = 'off';
                app.MParameter1C1DropDownLabel.Enable = 'off';
                app.MParameter2C1DropDown.Enable = 'off';
                app.MParameter2C1DropDownLabel.Enable = 'off';
                app.MParameter1C2DropDown.Enable = 'off';
                app.MParameter1C2DropDownLabel.Enable = 'off';
                app.MParameter2C2DropDown.Enable = 'off';
                app.MParameter2C2DropDownLabel.Enable = 'off';
                app.MParameter1C3DropDown.Enable = 'off';
                app.MParameter1C3DropDownLabel.Enable = 'off';
                app.MParameter2C3DropDown.Enable = 'off';
                app.MParameter2C3DropDownLabel.Enable = 'off';
                app.MParameter1C4DropDown.Enable = 'off';
                app.MParameter1C4DropDownLabel.Enable = 'off';
                app.MParameter2C4DropDown.Enable = 'off';
                app.MParameter2C4DropDownLabel.Enable = 'off';
            end
        end

        % Button pushed function: OutputBrowseButton
        function OutputBrowseButtonPushed(app, event)
            [filename, pathname] = uiputfile({ ...
                    '*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'; ...
                    '*.*', 'All Files (*.*)' ...
                }, ...
                'Select a EXCEL spreadsheet file');
            if ~isequal(filename, 0) && ~isequal(pathname, 0)
               app.OutputEditField.Value = fullfile(pathname, filename);
               app.ExportButton.Enable = 'on';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            conditions = {app.Condition1DropDown.Value, app.Condition2DropDown.Value, app.Condition3DropDown.Value, app.Condition4DropDown.Value};
            n_conditions = 4 - sum(count(conditions, 'None'));
            
            step_filename = app.TreadmillEditField.Value;
            metabolics_files = strsplit(app.MetabolicsEditField.Value, ';');
            output_filename = app.OutputEditField.Value;
                        
            if length(metabolics_files) < n_conditions
                uiwait(msgbox('Not enough metabolic data', 'Error', 'error'));
                return;
            end

            load(step_filename, 'adaptData');
            
            parameters = {app.Parameter1C1DropDown.Value, app.Parameter2C1DropDown.Value, app.Parameter3C1DropDown.Value, app.Parameter4C1DropDown.Value;
                          app.Parameter1C2DropDown.Value, app.Parameter2C2DropDown.Value, app.Parameter3C2DropDown.Value, app.Parameter4C2DropDown.Value;
                          app.Parameter1C3DropDown.Value, app.Parameter2C3DropDown.Value, app.Parameter3C3DropDown.Value, app.Parameter4C3DropDown.Value;
                          app.Parameter1C4DropDown.Value, app.Parameter2C4DropDown.Value, app.Parameter3C4DropDown.Value, app.Parameter4C4DropDown.Value};
            
            xlsx_files = {app.MFileC1DropDown.Value, app.MFileC2DropDown.Value, app.MFileC3DropDown.Value, app.MFileC4DropDown.Value};
            
            metabolics_parameters = {app.MParameter1C1DropDown.Value, app.MParameter2C1DropDown.Value;
                                     app.MParameter1C2DropDown.Value, app.MParameter2C2DropDown.Value;
                                     app.MParameter1C3DropDown.Value, app.MParameter2C3DropDown.Value;
                                     app.MParameter1C4DropDown.Value, app.MParameter2C4DropDown.Value};
                          
            if exist(output_filename, 'file')
                delete(output_filename);
            end
            
            for k = 1:n_conditions
                n_parameters = 4 - sum(count(parameters(k, :), 'None'));
                
                metabolics_filename = metabolics_files{endsWith(metabolics_files, xlsx_files{k})};
                
                p_data = cell(n_parameters, 1);
                max_len = 0;
                for j = 1:n_parameters
                    data = adaptData.getParamInCond(parameters{k, j}, conditions{k});
                    p_data{j} = data(isfinite(data(:)));
                    
                    if length(p_data{j}) > max_len
                        max_len = length(p_data{j});
                    end
                end

                if adaptData.data.TimeInfo.Units == 'seconds'
                    time_inc = adaptData.data.TimeInfo.Increment;
                end
                
                step_time = linspace(time_inc, max_len * time_inc, max_len)';
                
                % Read xlsx file
                [~, ~, raw] = xlsread(metabolics_filename);
                
                if strcmp(metabolics_parameters{k, 2}, 'None')
                    n_mparameters = 1;
                else
                    n_mparameters = 2;
                end
                
                metabolics_data = cell(n_mparameters, 1);
                for j = 1:n_mparameters
                    m_col = strcmpi(raw(1, :), metabolics_parameters{k, j});
                    metabolics_data{j} = [{[raw{1, m_col} ' [' raw{2, m_col} ']']}; raw(4:end, m_col)];
                end
                
                % Convert to an array of seconds representing each data acquisition time
                [~, ~, ~, H, MN, S] = datevec([raw{4:end, strcmpi(raw(1, :), 't')}]);
                metabolics_time = H.' * 3600 + MN.' * 60 + S.';
                metabolics_time = [{'Metabolics Time [s]'}; num2cell(metabolics_time)];
                
                step_time = [{'Stride'}; num2cell(step_time)];
                step_data = cell(max_len + 1, n_parameters);
                for j = 1:n_parameters
                    step_data(1:(length(p_data{j}) + 1), j) = [parameters(k, j); num2cell(p_data{j})];
                end
                steps = [step_time, step_data];
                metabolics = [metabolics_time metabolics_data{:}];
                
                if length(steps) > length(metabolics)
                    output_len = length(steps);
                else
                    output_len = length(metabolics);
                end
                output_data = cell(output_len, size(steps, 2) + size(metabolics, 2) + 1);
                
                output_data(1:length(steps), 1:size(steps, 2)) = steps;
                output_data(1:length(metabolics), (size(steps, 2) + 2):end) = metabolics;
                % Write output
                xlswrite(output_filename, output_data, conditions{k});
            end
            
            uiwait(msgbox('Export completed', 'Success', 'modal'));
        end

        % Value changed function: TreadmillEditField
        function TreadmillEditFieldValueChanged(app, event)
            value = app.TreadmillEditField.Value;
            
            if ~isempty(value)
                app.Condition1DropDown.Enable = 'on';
                app.Condition1DropDownLabel.Enable = 'on';
                
                if ~isempty(app.MetabolicsEditField.Value)
                    app.OutputEditField.Enable = 'on';
                    app.OutputEditFieldLabel.Enable = 'on';
                    app.OutputBrowseButton.Enable = 'on';
                else
                    app.OutputEditField.Enable = 'off';
                    app.OutputEditFieldLabel.Enable = 'off';
                    app.OutputBrowseButton.Enable = 'off';
                end
            else
                app.Condition1DropDown.Enable = 'on';
                app.Condition1DropDownLabel.Enable = 'on';
                app.OutputEditField.Enable = 'off';
                app.OutputEditFieldLabel.Enable = 'off';
                app.OutputBrowseButton.Enable = 'off';
            end
        end

        % Value changed function: MetabolicsEditField
        function MetabolicsEditFieldValueChanged(app, event)
            value = app.MetabolicsEditField.Value;
            if ~isempty(value) && ~isempty(app.TreadmillEditField.Value)
                app.OutputEditField.Enable = 'on';
                app.OutputEditFieldLabel.Enable = 'on';
                app.OutputBrowseButton.Enable = 'on';
            else
                app.OutputEditField.Enable = 'off';
                app.OutputEditFieldLabel.Enable = 'off';
                app.OutputBrowseButton.Enable = 'off';
            end
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            value = app.OutputEditField.Value;
            if ~isempty(value)
                app.ExportButton.Enable = 'on';
            else
                app.ExportButton.Enable = 'off';
            end
        end

        % Value changed function: Condition1DropDown
        function Condition1DropDownValueChanged(app, event)
            value = app.Condition1DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter1C1DropDown.Enable = 'on';
                app.Parameter1C1DropDownLabel.Enable = 'on';
                
                app.Condition2DropDown.Enable = 'on';
                app.Condition2DropDownLabel.Enable = 'on';
            else
                app.Parameter1C1DropDown.Value = 'None';
                app.Parameter1C1DropDown.Enable = 'off';
                app.Parameter1C1DropDownLabel.Enable = 'off';
                
                app.Parameter1C1DropDownValueChanged();
                
                app.Condition2DropDown.Value = 'None';
                app.Condition2DropDown.Enable = 'off';
                app.Condition2DropDownLabel.Enable = 'off';
                
                app.Condition2DropDownValueChanged();
            end
        end

        % Value changed function: Condition2DropDown
        function Condition2DropDownValueChanged(app, event)
            value = app.Condition2DropDown.Value;
                        
            if ~strcmp(value, 'None')
                app.Parameter1C2DropDown.Enable = 'on';
                app.Parameter1C2DropDownLabel.Enable = 'on';
                
                app.Condition3DropDown.Enable = 'on';
                app.Condition3DropDownLabel.Enable = 'on';
            else
                app.Parameter1C2DropDown.Value = 'None';
                app.Parameter1C2DropDown.Enable = 'off';
                app.Parameter1C2DropDownLabel.Enable = 'off';
                
                app.Parameter1C2DropDownValueChanged();
                
                app.Condition3DropDown.Value = 'None';
                app.Condition3DropDown.Enable = 'off';
                app.Condition3DropDownLabel.Enable = 'off';
                
                app.Condition3DropDownValueChanged();
            end
        end

        % Value changed function: Condition3DropDown
        function Condition3DropDownValueChanged(app, event)
            value = app.Condition3DropDown.Value;
                        
            if ~strcmp(value, 'None')
                app.Parameter1C3DropDown.Enable = 'on';
                app.Parameter1C3DropDownLabel.Enable = 'on';
                
                app.Condition4DropDown.Enable = 'on';
                app.Condition4DropDownLabel.Enable = 'on';
            else
                app.Parameter1C3DropDown.Value = 'None';
                app.Parameter1C3DropDown.Enable = 'off';
                app.Parameter1C3DropDownLabel.Enable = 'off';
                
                app.Parameter1C3DropDownValueChanged();
                
                app.Condition4DropDown.Value = 'None';
                app.Condition4DropDown.Enable = 'off';
                app.Condition4DropDownLabel.Enable = 'off';
                
                app.Condition4DropDownValueChanged();
            end
        end

        % Value changed function: Condition4DropDown
        function Condition4DropDownValueChanged(app, event)
            value = app.Condition4DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter1C4DropDownLabel.Enable = 'on';
                app.Parameter1C4DropDown.Enable = 'on';
            else
                app.Parameter1C4DropDownLabel.Enable = 'off';
                app.Parameter1C4DropDown.Enable = 'off';
                app.Parameter1C4DropDown.Value = 'None';
                
                app.Parameter1C4DropDownValueChanged();
            end
        end

        % Value changed function: Parameter1C1DropDown
        function Parameter1C1DropDownValueChanged(app, event)
            value = app.Parameter1C1DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter2C1DropDown.Enable = 'on';
                app.Parameter2C1DropDownLabel.Enable = 'on';
            else
                app.Parameter2C1DropDown.Value = 'None';
                app.Parameter2C1DropDown.Enable = 'off';
                app.Parameter2C1DropDownLabel.Enable = 'off';
                
                app.Parameter2C1DropDownValueChanged();
            end
        end

        % Value changed function: Parameter2C1DropDown
        function Parameter2C1DropDownValueChanged(app, event)
            value = app.Parameter2C1DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter3C1DropDown.Enable = 'on';
                app.Parameter3C1DropDownLabel.Enable = 'on';
            else
                app.Parameter3C1DropDown.Value = 'None';
                app.Parameter3C1DropDown.Enable = 'off';
                app.Parameter3C1DropDownLabel.Enable = 'off';
                
                app.Parameter3C1DropDownValueChanged();
            end
        end

        % Value changed function: Parameter3C1DropDown
        function Parameter3C1DropDownValueChanged(app, event)
            value = app.Parameter3C1DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter4C1DropDown.Enable = 'on';
                app.Parameter4C1DropDownLabel.Enable = 'on';
            else
                app.Parameter4C1DropDown.Value = 'None';
                app.Parameter4C1DropDown.Enable = 'off';
                app.Parameter4C1DropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: Parameter1C2DropDown
        function Parameter1C2DropDownValueChanged(app, event)
            value = app.Parameter1C2DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter2C2DropDown.Enable = 'on';
                app.Parameter2C2DropDownLabel.Enable = 'on';
            else
                app.Parameter2C2DropDown.Value = 'None';
                app.Parameter2C2DropDown.Enable = 'off';
                app.Parameter2C2DropDownLabel.Enable = 'off';
                
                app.Parameter2C2DropDownValueChanged();
            end
        end

        % Value changed function: Parameter2C2DropDown
        function Parameter2C2DropDownValueChanged(app, event)
            value = app.Parameter2C2DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter3C2DropDown.Enable = 'on';
                app.Parameter3C2DropDownLabel.Enable = 'on';
            else
                app.Parameter3C2DropDown.Value = 'None';
                app.Parameter3C2DropDown.Enable = 'off';
                app.Parameter3C2DropDownLabel.Enable = 'off';
                
                app.Parameter3C2DropDownValueChanged();
            end
        end

        % Value changed function: Parameter3C2DropDown
        function Parameter3C2DropDownValueChanged(app, event)
            value = app.Parameter3C2DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter4C2DropDown.Enable = 'on';
                app.Parameter4C2DropDownLabel.Enable = 'on';
            else
                app.Parameter4C2DropDown.Value = 'None';
                app.Parameter4C2DropDown.Enable = 'off';
                app.Parameter4C2DropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: Parameter1C3DropDown
        function Parameter1C3DropDownValueChanged(app, event)
            value = app.Parameter1C3DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter2C3DropDown.Enable = 'on';
                app.Parameter2C3DropDownLabel.Enable = 'on';
            else
                app.Parameter2C3DropDown.Value = 'None';
                app.Parameter2C3DropDown.Enable = 'off';
                app.Parameter2C3DropDownLabel.Enable = 'off';
                
                app.Parameter2C3DropDownValueChanged();
            end
        end

        % Value changed function: Parameter2C3DropDown
        function Parameter2C3DropDownValueChanged(app, event)
            value = app.Parameter2C3DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter3C3DropDown.Enable = 'on';
                app.Parameter3C3DropDownLabel.Enable = 'on';
            else
                app.Parameter3C3DropDown.Value = 'None';
                app.Parameter3C3DropDown.Enable = 'off';
                app.Parameter3C3DropDownLabel.Enable = 'off';
                
                app.Parameter3C3DropDownValueChanged();
            end
        end

        % Value changed function: Parameter3C3DropDown
        function Parameter3C3DropDownValueChanged(app, event)
            value = app.Parameter3C3DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter4C3DropDown.Enable = 'on';
                app.Parameter4C3DropDownLabel.Enable = 'on';
            else
                app.Parameter4C3DropDown.Value = 'None';
                app.Parameter4C3DropDown.Enable = 'off';
                app.Parameter4C3DropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: Parameter1C4DropDown
        function Parameter1C4DropDownValueChanged(app, event)
            value = app.Parameter1C4DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter2C4DropDown.Enable = 'on';
                app.Parameter2C4DropDownLabel.Enable = 'on';
            else
                app.Parameter2C4DropDown.Value = 'None';
                app.Parameter2C4DropDown.Enable = 'off';
                app.Parameter2C4DropDownLabel.Enable = 'off';
                
                app.Parameter2C4DropDownValueChanged();
            end
        end

        % Value changed function: Parameter2C4DropDown
        function Parameter2C4DropDownValueChanged(app, event)
            value = app.Parameter2C4DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter3C4DropDown.Enable = 'on';
                app.Parameter3C4DropDownLabel.Enable = 'on';
            else
                app.Parameter3C4DropDown.Value = 'None';
                app.Parameter3C4DropDown.Enable = 'off';
                app.Parameter3C4DropDownLabel.Enable = 'off';
                
                app.Parameter3C4DropDownValueChanged();
            end
        end

        % Value changed function: Parameter3C4DropDown
        function Parameter3C4DropDownValueChanged(app, event)
            value = app.Parameter3C4DropDown.Value;
            
            if ~strcmp(value, 'None')
                app.Parameter4C4DropDown.Enable = 'on';
                app.Parameter4C4DropDownLabel.Enable = 'on';
            else
                app.Parameter4C4DropDown.Value = 'None';
                app.Parameter4C4DropDown.Enable = 'off';
                app.Parameter4C4DropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: MFileC1DropDown
        function MFileC1DropDownValueChanged(app, event)
            value = app.MFileC1DropDown.Value;
            
            files = strsplit(app.MetabolicsEditField.Value, ';');
            filename = files{endsWith(files, value)};
            [~, ~, raw] = xlsread(filename);
            col = find(strcmp(raw(1, :), 't'));
            app.MParameter1C1DropDown.Items = raw(1, col:end);
            app.MParameter2C1DropDown.Items = [{'None'}, raw(1, col:end)];
        end

        % Value changed function: MFileC2DropDown
        function MFileC2DropDownValueChanged(app, event)
            value = app.MFileC2DropDown.Value;
            
            files = strsplit(app.MetabolicsEditField.Value, ';');
            filename = files{endsWith(files, value)};
            [~, ~, raw] = xlsread(filename);
            col = find(strcmp(raw(1, :), 't'));
            app.MParameter1C2DropDown.Items = raw(1, col:end);
            app.MParameter2C2DropDown.Items = [{'None'}, raw(1, col:end)];
        end

        % Value changed function: MFileC3DropDown
        function MFileC3DropDownValueChanged(app, event)
            value = app.MFileC3DropDown.Value;
            
            files = strsplit(app.MetabolicsEditField.Value, ';');
            filename = files{endsWith(files, value)};
            [~, ~, raw] = xlsread(filename);
            col = find(strcmp(raw(1, :), 't'));
            app.MParameter1C3DropDown.Items = raw(1, col:end);
            app.MParameter2C3DropDown.Items = [{'None'}, raw(1, col:end)];
        end

        % Value changed function: MFileC4DropDown
        function MFileC4DropDownValueChanged(app, event)
            value = app.MFileC4DropDown.Value;
            
            files = strsplit(app.MetabolicsEditField.Value, ';');
            filename = files{endsWith(files, value)};
            [~, ~, raw] = xlsread(filename);
            col = find(strcmp(raw(1, :), 't'));
            app.MParameter1C4DropDown.Items = raw(1, col:end);
            app.MParameter2C4DropDown.Items = [{'None'}, raw(1, col:end)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportUIFigure = uifigure;
            app.ExportUIFigure.Name = 'Export data';
            app.TreadmillEditFieldLabel = uilabel(app.ExportUIFigure);
            app.TreadmillEditFieldLabel.Text = 'Treadmill data file';
            app.TreadmillEditField = uieditfield(app.ExportUIFigure, 'text');
            app.TreadmillEditField.ValueChangedFcn = createCallbackFcn(app, @TreadmillEditFieldValueChanged, true);
            app.TreadmillEditField.Editable = 'off';
            app.TreadmillBrowseButton = uibutton(app.ExportUIFigure, 'push');
            app.TreadmillBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TreadmillBrowseButtonPushed, true);
            app.TreadmillBrowseButton.Text = 'Browse';
            app.MetabolicsBrowseButton = uibutton(app.ExportUIFigure, 'push');
            app.MetabolicsBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MetabolicsBrowseButtonPushed, true);
            app.MetabolicsBrowseButton.Text = 'Browse';
            app.MetabolicsEditFieldLabel = uilabel(app.ExportUIFigure);
            app.MetabolicsEditFieldLabel.Text = 'Metabolics data file';
            app.MetabolicsEditField = uieditfield(app.ExportUIFigure, 'text');
            app.MetabolicsEditField.ValueChangedFcn = createCallbackFcn(app, @MetabolicsEditFieldValueChanged, true);
            app.MetabolicsEditField.Editable = 'off';
            app.OutputBrowseButton = uibutton(app.ExportUIFigure, 'push');
            app.OutputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputBrowseButtonPushed, true);
            app.OutputBrowseButton.Enable = 'off';
            app.OutputBrowseButton.Text = 'Browse';
            app.OutputEditFieldLabel = uilabel(app.ExportUIFigure);
            app.OutputEditFieldLabel.Enable = 'off';
            app.OutputEditFieldLabel.Text = 'Output file';
            app.OutputEditField = uieditfield(app.ExportUIFigure, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.OutputEditField.Editable = 'off';
            app.OutputEditField.Enable = 'off';
            app.ExportButton = uibutton(app.ExportUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.TabGroup = uitabgroup(app.ExportUIFigure);
            app.Condition1Tab = uitab(app.TabGroup);
            app.Condition1Tab.Title = 'Condition 1';
            app.Condition1DropDownLabel = uilabel(app.Condition1Tab);
            app.Condition1DropDownLabel.Enable = 'off';
            app.Condition1DropDownLabel.Text = 'Condition';
            app.Condition1DropDown = uidropdown(app.Condition1Tab);
            app.Condition1DropDown.Items = {'None'};
            app.Condition1DropDown.ValueChangedFcn = createCallbackFcn(app, @Condition1DropDownValueChanged, true);
            app.Condition1DropDown.Enable = 'off';
            app.Condition1DropDown.Value = 'None';
            app.Parameter1C1DropDownLabel = uilabel(app.Condition1Tab);
            app.Parameter1C1DropDownLabel.Enable = 'off';
            app.Parameter1C1DropDownLabel.Text = 'Parameter 1';
            app.Parameter1C1DropDown = uidropdown(app.Condition1Tab);
            app.Parameter1C1DropDown.Items = {'None'};
            app.Parameter1C1DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1C1DropDownValueChanged, true);
            app.Parameter1C1DropDown.Enable = 'off';
            app.Parameter1C1DropDown.Value = 'None';
            app.Parameter2C1DropDownLabel = uilabel(app.Condition1Tab);
            app.Parameter2C1DropDownLabel.Enable = 'off';
            app.Parameter2C1DropDownLabel.Text = 'Parameter 2';
            app.Parameter2C1DropDown = uidropdown(app.Condition1Tab);
            app.Parameter2C1DropDown.Items = {'None'};
            app.Parameter2C1DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2C1DropDownValueChanged, true);
            app.Parameter2C1DropDown.Enable = 'off';
            app.Parameter2C1DropDown.Value = 'None';
            app.Parameter3C1DropDownLabel = uilabel(app.Condition1Tab);
            app.Parameter3C1DropDownLabel.Enable = 'off';
            app.Parameter3C1DropDownLabel.Text = 'Parameter 3';
            app.Parameter3C1DropDown = uidropdown(app.Condition1Tab);
            app.Parameter3C1DropDown.Items = {'None'};
            app.Parameter3C1DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter3C1DropDownValueChanged, true);
            app.Parameter3C1DropDown.Enable = 'off';
            app.Parameter3C1DropDown.Value = 'None';
            app.Parameter4C1DropDownLabel = uilabel(app.Condition1Tab);
            app.Parameter4C1DropDownLabel.Enable = 'off';
            app.Parameter4C1DropDownLabel.Text = 'Parameter 4';
            app.Parameter4C1DropDown = uidropdown(app.Condition1Tab);
            app.Parameter4C1DropDown.Items = {'None'};
            app.Parameter4C1DropDown.Enable = 'off';
            app.Parameter4C1DropDown.Value = 'None';
            app.MetabolicsC1Panel = uipanel(app.Condition1Tab);
            app.MetabolicsC1Panel.Title = 'Metabolics';
            app.MFileC1DropDownLabel = uilabel(app.MetabolicsC1Panel);
            app.MFileC1DropDownLabel.Enable = 'off';
            app.MFileC1DropDownLabel.Text = 'File';
            app.MFileC1DropDown = uidropdown(app.MetabolicsC1Panel);
            app.MFileC1DropDown.Items = {'None'};
            app.MFileC1DropDown.ValueChangedFcn = createCallbackFcn(app, @MFileC1DropDownValueChanged, true);
            app.MFileC1DropDown.Enable = 'off';
            app.MFileC1DropDown.Value = 'None';
            app.MParameter1C1DropDownLabel = uilabel(app.MetabolicsC1Panel);
            app.MParameter1C1DropDownLabel.Enable = 'off';
            app.MParameter1C1DropDownLabel.Text = 'Parameter 1';
            app.MParameter1C1DropDown = uidropdown(app.MetabolicsC1Panel);
            app.MParameter1C1DropDown.Items = {'None'};
            app.MParameter1C1DropDown.Enable = 'off';
            app.MParameter1C1DropDown.Value = 'None';
            app.MParameter2C1DropDownLabel = uilabel(app.MetabolicsC1Panel);
            app.MParameter2C1DropDownLabel.Enable = 'off';
            app.MParameter2C1DropDownLabel.Text = 'Parameter 2';
            app.MParameter2C1DropDown = uidropdown(app.MetabolicsC1Panel);
            app.MParameter2C1DropDown.Items = {'None'};
            app.MParameter2C1DropDown.Enable = 'off';
            app.MParameter2C1DropDown.Value = 'None';
            app.Condition2Tab = uitab(app.TabGroup);
            app.Condition2Tab.Title = 'Condition 2';
            app.Condition2DropDownLabel = uilabel(app.Condition2Tab);
            app.Condition2DropDownLabel.Enable = 'off';
            app.Condition2DropDownLabel.Text = 'Condition';
            app.Condition2DropDown = uidropdown(app.Condition2Tab);
            app.Condition2DropDown.Items = {'None'};
            app.Condition2DropDown.ValueChangedFcn = createCallbackFcn(app, @Condition2DropDownValueChanged, true);
            app.Condition2DropDown.Enable = 'off';
            app.Condition2DropDown.Value = 'None';
            app.Parameter1C2DropDownLabel = uilabel(app.Condition2Tab);
            app.Parameter1C2DropDownLabel.Enable = 'off';
            app.Parameter1C2DropDownLabel.Text = 'Parameter 1';
            app.Parameter1C2DropDown = uidropdown(app.Condition2Tab);
            app.Parameter1C2DropDown.Items = {'None'};
            app.Parameter1C2DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1C2DropDownValueChanged, true);
            app.Parameter1C2DropDown.Enable = 'off';
            app.Parameter1C2DropDown.Value = 'None';
            app.Parameter2C2DropDownLabel = uilabel(app.Condition2Tab);
            app.Parameter2C2DropDownLabel.Enable = 'off';
            app.Parameter2C2DropDownLabel.Text = 'Parameter 2';
            app.Parameter2C2DropDown = uidropdown(app.Condition2Tab);
            app.Parameter2C2DropDown.Items = {'None'};
            app.Parameter2C2DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2C2DropDownValueChanged, true);
            app.Parameter2C2DropDown.Enable = 'off';
            app.Parameter2C2DropDown.Value = 'None';
            app.Parameter3C2DropDownLabel = uilabel(app.Condition2Tab);
            app.Parameter3C2DropDownLabel.Enable = 'off';
            app.Parameter3C2DropDownLabel.Text = 'Parameter 3';
            app.Parameter3C2DropDown = uidropdown(app.Condition2Tab);
            app.Parameter3C2DropDown.Items = {'None'};
            app.Parameter3C2DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter3C2DropDownValueChanged, true);
            app.Parameter3C2DropDown.Enable = 'off';
            app.Parameter3C2DropDown.Value = 'None';
            app.Parameter4C2DropDownLabel = uilabel(app.Condition2Tab);
            app.Parameter4C2DropDownLabel.Enable = 'off';
            app.Parameter4C2DropDownLabel.Text = 'Parameter 4';
            app.Parameter4C2DropDown = uidropdown(app.Condition2Tab);
            app.Parameter4C2DropDown.Items = {'None'};
            app.Parameter4C2DropDown.Enable = 'off';
            app.Parameter4C2DropDown.Value = 'None';
            app.MetabolicsC2Panel = uipanel(app.Condition2Tab);
            app.MetabolicsC2Panel.Title = 'Metabolics';
            app.MFileC2DropDownLabel = uilabel(app.MetabolicsC2Panel);
            app.MFileC2DropDownLabel.Enable = 'off';
            app.MFileC2DropDownLabel.Text = 'File';
            app.MFileC2DropDown = uidropdown(app.MetabolicsC2Panel);
            app.MFileC2DropDown.Items = {'None'};
            app.MFileC2DropDown.ValueChangedFcn = createCallbackFcn(app, @MFileC2DropDownValueChanged, true);
            app.MFileC2DropDown.Enable = 'off';
            app.MFileC2DropDown.Value = 'None';
            app.MParameter1C2DropDownLabel = uilabel(app.MetabolicsC2Panel);
            app.MParameter1C2DropDownLabel.Enable = 'off';
            app.MParameter1C2DropDownLabel.Text = 'Parameter 1';
            app.MParameter1C2DropDown = uidropdown(app.MetabolicsC2Panel);
            app.MParameter1C2DropDown.Items = {'None'};
            app.MParameter1C2DropDown.Enable = 'off';
            app.MParameter1C2DropDown.Value = 'None';
            app.MParameter2C2DropDownLabel = uilabel(app.MetabolicsC2Panel);
            app.MParameter2C2DropDownLabel.Enable = 'off';
            app.MParameter2C2DropDownLabel.Text = 'Parameter 2';
            app.MParameter2C2DropDown = uidropdown(app.MetabolicsC2Panel);
            app.MParameter2C2DropDown.Items = {'None'};
            app.MParameter2C2DropDown.Enable = 'off';
            app.MParameter2C2DropDown.Value = 'None';
            app.Condition3Tab = uitab(app.TabGroup);
            app.Condition3Tab.Title = 'Condition 3';
            app.Condition3DropDownLabel = uilabel(app.Condition3Tab);
            app.Condition3DropDownLabel.Enable = 'off';
            app.Condition3DropDownLabel.Text = 'Condition';
            app.Condition3DropDown = uidropdown(app.Condition3Tab);
            app.Condition3DropDown.Items = {'None'};
            app.Condition3DropDown.ValueChangedFcn = createCallbackFcn(app, @Condition3DropDownValueChanged, true);
            app.Condition3DropDown.Enable = 'off';
            app.Condition3DropDown.Value = 'None';
            app.Parameter1C3DropDownLabel = uilabel(app.Condition3Tab);
            app.Parameter1C3DropDownLabel.Enable = 'off';
            app.Parameter1C3DropDownLabel.Text = 'Parameter 1';
            app.Parameter1C3DropDown = uidropdown(app.Condition3Tab);
            app.Parameter1C3DropDown.Items = {'None'};
            app.Parameter1C3DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1C3DropDownValueChanged, true);
            app.Parameter1C3DropDown.Enable = 'off';
            app.Parameter1C3DropDown.Value = 'None';
            app.Parameter2C3DropDownLabel = uilabel(app.Condition3Tab);
            app.Parameter2C3DropDownLabel.Enable = 'off';
            app.Parameter2C3DropDownLabel.Text = 'Parameter 2';
            app.Parameter2C3DropDown = uidropdown(app.Condition3Tab);
            app.Parameter2C3DropDown.Items = {'None'};
            app.Parameter2C3DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2C3DropDownValueChanged, true);
            app.Parameter2C3DropDown.Enable = 'off';
            app.Parameter2C3DropDown.Value = 'None';
            app.Parameter3C3DropDownLabel = uilabel(app.Condition3Tab);
            app.Parameter3C3DropDownLabel.Enable = 'off';
            app.Parameter3C3DropDownLabel.Text = 'Parameter 3';
            app.Parameter3C3DropDown = uidropdown(app.Condition3Tab);
            app.Parameter3C3DropDown.Items = {'None'};
            app.Parameter3C3DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter3C3DropDownValueChanged, true);
            app.Parameter3C3DropDown.Enable = 'off';
            app.Parameter3C3DropDown.Value = 'None';
            app.Parameter4C3DropDownLabel = uilabel(app.Condition3Tab);
            app.Parameter4C3DropDownLabel.Enable = 'off';
            app.Parameter4C3DropDownLabel.Text = 'Parameter 4';
            app.Parameter4C3DropDown = uidropdown(app.Condition3Tab);
            app.Parameter4C3DropDown.Items = {'None'};
            app.Parameter4C3DropDown.Enable = 'off';
            app.Parameter4C3DropDown.Value = 'None';
            app.MetabolicsC3Panel = uipanel(app.Condition3Tab);
            app.MetabolicsC3Panel.Title = 'Metabolics';
            app.MFileC3DropDownLabel = uilabel(app.MetabolicsC3Panel);
            app.MFileC3DropDownLabel.Enable = 'off';
            app.MFileC3DropDownLabel.Text = 'File';
            app.MFileC3DropDown = uidropdown(app.MetabolicsC3Panel);
            app.MFileC3DropDown.Items = {'None'};
            app.MFileC3DropDown.ValueChangedFcn = createCallbackFcn(app, @MFileC3DropDownValueChanged, true);
            app.MFileC3DropDown.Enable = 'off';
            app.MFileC3DropDown.Value = 'None';
            app.MParameter1C3DropDownLabel = uilabel(app.MetabolicsC3Panel);
            app.MParameter1C3DropDownLabel.Enable = 'off';
            app.MParameter1C3DropDownLabel.Text = 'Parameter 1';
            app.MParameter1C3DropDown = uidropdown(app.MetabolicsC3Panel);
            app.MParameter1C3DropDown.Items = {'None'};
            app.MParameter1C3DropDown.Enable = 'off';
            app.MParameter1C3DropDown.Value = 'None';
            app.MParameter2C3DropDownLabel = uilabel(app.MetabolicsC3Panel);
            app.MParameter2C3DropDownLabel.Enable = 'off';
            app.MParameter2C3DropDownLabel.Text = 'Parameter 2';
            app.MParameter2C3DropDown = uidropdown(app.MetabolicsC3Panel);
            app.MParameter2C3DropDown.Items = {'None'};
            app.MParameter2C3DropDown.Enable = 'off';
            app.MParameter2C3DropDown.Value = 'None';
            app.Condition4Tab = uitab(app.TabGroup);
            app.Condition4Tab.Title = 'Condition 4';
            app.Condition4DropDownLabel = uilabel(app.Condition4Tab);
            app.Condition4DropDownLabel.Enable = 'off';
            app.Condition4DropDownLabel.Text = 'Condition';
            app.Condition4DropDown = uidropdown(app.Condition4Tab);
            app.Condition4DropDown.Items = {'None'};
            app.Condition4DropDown.ValueChangedFcn = createCallbackFcn(app, @Condition4DropDownValueChanged, true);
            app.Condition4DropDown.Enable = 'off';
            app.Condition4DropDown.Value = 'None';
            app.Parameter1C4DropDownLabel = uilabel(app.Condition4Tab);
            app.Parameter1C4DropDownLabel.Enable = 'off';
            app.Parameter1C4DropDownLabel.Text = 'Parameter 1';
            app.Parameter1C4DropDown = uidropdown(app.Condition4Tab);
            app.Parameter1C4DropDown.Items = {'None'};
            app.Parameter1C4DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1C4DropDownValueChanged, true);
            app.Parameter1C4DropDown.Enable = 'off';
            app.Parameter1C4DropDown.Value = 'None';
            app.Parameter2C4DropDownLabel = uilabel(app.Condition4Tab);
            app.Parameter2C4DropDownLabel.Enable = 'off';
            app.Parameter2C4DropDownLabel.Text = 'Parameter 2';
            app.Parameter2C4DropDown = uidropdown(app.Condition4Tab);
            app.Parameter2C4DropDown.Items = {'None'};
            app.Parameter2C4DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2C4DropDownValueChanged, true);
            app.Parameter2C4DropDown.Enable = 'off';
            app.Parameter2C4DropDown.Value = 'None';
            app.Parameter3C4DropDownLabel = uilabel(app.Condition4Tab);
            app.Parameter3C4DropDownLabel.Enable = 'off';
            app.Parameter3C4DropDownLabel.Text = 'Parameter 3';
            app.Parameter3C4DropDown = uidropdown(app.Condition4Tab);
            app.Parameter3C4DropDown.Items = {'None'};
            app.Parameter3C4DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter3C4DropDownValueChanged, true);
            app.Parameter3C4DropDown.Enable = 'off';
            app.Parameter3C4DropDown.Value = 'None';
            app.Parameter4C4DropDownLabel = uilabel(app.Condition4Tab);
            app.Parameter4C4DropDownLabel.Enable = 'off';
            app.Parameter4C4DropDownLabel.Text = 'Parameter 4';
            app.Parameter4C4DropDown = uidropdown(app.Condition4Tab);
            app.Parameter4C4DropDown.Items = {'None'};
            app.Parameter4C4DropDown.Enable = 'off';
            app.Parameter4C4DropDown.Value = 'None';
            app.MetabolicsC4Panel = uipanel(app.Condition4Tab);
            app.MetabolicsC4Panel.Title = 'Metabolics';
            app.MFileC4DropDownLabel = uilabel(app.MetabolicsC4Panel);
            app.MFileC4DropDownLabel.Enable = 'off';
            app.MFileC4DropDownLabel.Text = 'File';
            app.MFileC4DropDown = uidropdown(app.MetabolicsC4Panel);
            app.MFileC4DropDown.Items = {'None'};
            app.MFileC4DropDown.ValueChangedFcn = createCallbackFcn(app, @MFileC4DropDownValueChanged, true);
            app.MFileC4DropDown.Enable = 'off';
            app.MFileC4DropDown.Value = 'None';
            app.MParameter1C4DropDownLabel = uilabel(app.MetabolicsC4Panel);
            app.MParameter1C4DropDownLabel.Enable = 'off';
            app.MParameter1C4DropDownLabel.Text = 'Parameter 1';
            app.MParameter1C4DropDown = uidropdown(app.MetabolicsC4Panel);
            app.MParameter1C4DropDown.Items = {'None'};
            app.MParameter1C4DropDown.Enable = 'off';
            app.MParameter1C4DropDown.Value = 'None';
            app.MParameter2C4DropDownLabel = uilabel(app.MetabolicsC4Panel);
            app.MParameter2C4DropDownLabel.Enable = 'off';
            app.MParameter2C4DropDownLabel.Text = 'Parameter 2';
            app.MParameter2C4DropDown = uidropdown(app.MetabolicsC4Panel);
            app.MParameter2C4DropDown.Items = {'None'};
            app.MParameter2C4DropDown.Enable = 'off';
            app.MParameter2C4DropDown.Value = 'None';
        end
    end

    methods (Access = public)

        % Construct app
        function app = data_export

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportUIFigure)
        end
    end
end